classdef CAE_LungsClassification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        Image                  matlab.ui.control.Image
        ClassifyButton         matlab.ui.control.Button
        CAMScoreButton         matlab.ui.control.Button
        NormalvsCOVIDvsPneumoniaLungsClassificationLabel  matlab.ui.control.Label
        ComputerAidedEngineeringFinalProjectLabel  matlab.ui.control.Label
        ResetButton            matlab.ui.control.Button
        EditField_4            matlab.ui.control.EditField
        EditField_3            matlab.ui.control.EditField
        EditField_2            matlab.ui.control.EditField
        EditField              matlab.ui.control.EditField
        ImageProcessingButton  matlab.ui.control.Button
        LoadImageButton        matlab.ui.control.Button
        UIAxes_8               matlab.ui.control.UIAxes
        UIAxes_7               matlab.ui.control.UIAxes
        UIAxes_6               matlab.ui.control.UIAxes
        UIAxes_5               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_4               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global Img
            [FileName, PathName] = uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'},'Please select an Image');
            Img = imread([PathName FileName]);
            imshow(Img,'Parent',app.UIAxes);
%             title('Original Image');
        end

        % Button pushed function: ImageProcessingButton
        function ImageProcessingButtonPushed(app, event)
            %Contrast enhancement image
            global Img
            global ImHE
            global ImGamma
            global ImSegmented
            
            
            %Histogram Equalization Image
            ImHE = histeq(Img);
            
            imshow(ImHE,'Parent',app.UIAxes_2);
%             title('Histogram Equalization Lungs Image');
            
            %Gamma Correction Image
            ImGamma = lin2rgb(Img);
            
            imshow(ImGamma,'Parent',app.UIAxes_3);
%             title('Gamma Correction Lungs Image');
            
            % Segmented Image
            load('unet_retrain2.mat')
            [C, scores] = semanticseg(Img,net);
            BW = C == 'lungs';
            ImSegmented = zeros(size(Img),'like',Img);
            ImSegmented(~BW) = Img(~BW);
            
            imshow(ImSegmented,'Parent', app.UIAxes_4);
%             title('Segmented Lungs Image');
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            global Img
            global ImHE
            global ImGamma
            global map_o
            global map_h
            global map_g
            global map
            global I
            global ImSegmented
            
            %Original
            %ResNet18 Input Size
            inputSize = [224 224 3];
            
            % Original 
            load('ResNet18_Original.mat');
            augimdsTesting = augmentedImageDatastore(inputSize(1:2),Img,'ColorPreprocessing','gray2rgb');
            [labels,score] = classify(Modelori, augimdsTesting);
            fprintf('The classification Original class is: %s\n',labels);
            
            map_o = gradCAM(Modelori,editimagever2(Img),labels);


            app.EditField.Value = sprintf('%s',labels);
            
            %-----------------------------------------------------------------------------------------------%

            %Histogram Equalization
            load('ResNet18_Histogram.mat');
            augimdsTesting = augmentedImageDatastore(inputSize(1:2),ImHE,'ColorPreprocessing','gray2rgb');
            [labels,score] = classify(Modelhe, augimdsTesting);
            fprintf('The classification Histogram Equalizatoin class is: %s\n',labels);
            
            map_h = gradCAM(Modelhe,editimagever2(ImHE),labels);


            app.EditField_2.Value = sprintf('%s',labels);

           %-----------------------------------------------------------------------------------------------%

            %Gamma Correction
            load('ResNet18_Gamma.mat');
            augimdsTesting = augmentedImageDatastore(inputSize(1:2),ImGamma,'ColorPreprocessing','gray2rgb');
            [labels,score] = classify(Modelgamma, augimdsTesting);
            fprintf('The classification Gamma Correction class is: %s\n',labels);
            
            map_g = gradCAM(Modelgamma,editimagever2(ImGamma),labels);
            
         
            app.EditField_3.Value = sprintf('%s',labels);

            %-----------------------------------------------------------------------------------------------%

            %Lung Segmentation
            load('ResNet18_Segmented.mat');
            augimdsTesting = augmentedImageDatastore(inputSize(1:2),ImSegmented,'ColorPreprocessing','gray2rgb');
            [labels,score] = classify(Modelsegmented, augimdsTesting);
            fprintf('The classification Image Segmentation class is: %s\n',labels);
            
            map = gradCAM(Modelsegmented,editimagever2(Img),labels);
            I = editimage(ImSegmented);


            app.EditField_4.Value = sprintf('%s',labels);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
                        cla(app.UIAxes_5);
                                    cla(app.UIAxes_6);
                                                cla(app.UIAxes_7);
                                                            cla(app.UIAxes_8);
            app.EditField.Value = char('');
            app.EditField_2.Value = char('');
            app.EditField_3.Value = char('');
            app.EditField_4.Value = char('');
            f = msgbox('Please load the new file!','Reset');
        end

        % Button pushed function: CAMScoreButton
        function CAMScoreButtonPushed(app, event)
            global Img
            global ImHE
            global ImGamma
            global map_o
            global map_h
            global map_g
            global map
            global I

            %Original CAMScore            
            imshow(editimagever2(Img),'InitialMagnification','fit','Parent',app.UIAxes_5)
            hold(app.UIAxes_5, 'on')
            ho = imagesc(map_o,'AlphaData',0.3,'Parent',app.UIAxes_5);
            colormap(app.UIAxes_5, 'jet')
            hold(app.UIAxes_5, 'off')

            %Histogram Equalization CAMScore
            imshow(editimagever2(ImHE),'InitialMagnification','fit','Parent',app.UIAxes_6)
            hold(app.UIAxes_6, 'on')
            hh = imagesc(map_h,'AlphaData',0.3,'Parent',app.UIAxes_6);
            colormap(app.UIAxes_6, 'jet')
            hold(app.UIAxes_6, 'off')

            %Gamma Correction CAMScore
            imshow(editimagever2(ImGamma),'InitialMagnification','fit','Parent',app.UIAxes_7)
            hold(app.UIAxes_7, 'on')
            hg = imagesc(map_g,'AlphaData',0.3,'Parent',app.UIAxes_7);
            colormap(app.UIAxes_7, 'jet')
            hold(app.UIAxes_7, 'off')

            %Lung Segmentation CAMScore
            imshow(editimagever2(Img),'InitialMagnification','fit','Parent',app.UIAxes_8)
            hold(app.UIAxes_8, 'on')
            h = imagesc(map,'AlphaData',0.3,'Parent',app.UIAxes_8);
            colormap(app.UIAxes_8, 'jet')
            hold(app.UIAxes_8, 'off')
            set(h,'AlphaData',I)
        end

        % Callback function
        function ClearImageButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel);
            title(app.UIAxes_2, 'Histogram Equalization ')
            app.UIAxes_3 = uiaxes(app.Panel);
            title(app.UIAxes_3, 'Gamma Correction')
            app.UIAxes_4 = uiaxes(app.Panel);
            title(app.UIAxes_4, 'Lung Segmentation')
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Original Image')
            app.UIAxes_5 = uiaxes(app.Panel);
            title(app.UIAxes_5, 'Original Image CAM Score')
            app.UIAxes_6 = uiaxes(app.Panel);
            title(app.UIAxes_6, 'Histogram Equalization CAM Score')
            app.UIAxes_7 = uiaxes(app.Panel);
            title(app.UIAxes_7, 'Gamma Correction CAM Score')
            app.UIAxes_8 = uiaxes(app.Panel);
            title(app.UIAxes_8, 'Lung Segmentation CAM Score')
            app.LoadImageButton = uibutton(app.Panel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ImageProcessingButton = uibutton(app.Panel, 'push');
            app.ImageProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @ImageProcessingButtonPushed, true);
            app.ImageProcessingButton.Text = 'Image Processing';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_3 = uieditfield(app.Panel, 'text');
            app.EditField_4 = uieditfield(app.Panel, 'text');
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ComputerAidedEngineeringFinalProjectLabel = uilabel(app.Panel);
            app.ComputerAidedEngineeringFinalProjectLabel.Text = 'Computer Aided Engineering Final Project';
            app.NormalvsCOVIDvsPneumoniaLungsClassificationLabel = uilabel(app.Panel);
            app.NormalvsCOVIDvsPneumoniaLungsClassificationLabel.Text = 'Normal vs. COVID vs. Pneumonia Lungs  Classification';
            app.CAMScoreButton = uibutton(app.Panel, 'push');
            app.CAMScoreButton.ButtonPushedFcn = createCallbackFcn(app, @CAMScoreButtonPushed, true);
            app.CAMScoreButton.Text = 'CAM Score';
            app.ClassifyButton = uibutton(app.Panel, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'ResetButton.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CAE_LungsClassification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end